classdef ButtonPushTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ButtonPushTrackerv100Label     matlab.ui.control.Label
        ByAndrewCongdonLabel           matlab.ui.control.Label
        TotalPush                      matlab.ui.control.NumericEditField
        TotalPushesABCDEditFieldLabel  matlab.ui.control.Label
        Reset                          matlab.ui.control.Button
        Random                         matlab.ui.control.Button
        Subtract                       matlab.ui.control.Button
        Add                            matlab.ui.control.Button
        Dedit                          matlab.ui.control.NumericEditField
        TotalDPushesLabel              matlab.ui.control.Label
        Cedit                          matlab.ui.control.NumericEditField
        TotalCPushesLabel              matlab.ui.control.Label
        Bedit                          matlab.ui.control.NumericEditField
        TotalBPushesLabel              matlab.ui.control.Label
        Aedit                          matlab.ui.control.NumericEditField
        TotalAPushesLabel              matlab.ui.control.Label
        Dbutton                        matlab.ui.control.Button
        Cbutton                        matlab.ui.control.Button
        Bbutton                        matlab.ui.control.Button
        Abutton                        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Abutton
        function AbuttonPushed(app, event)
            % Add 1 to A and the total
            app.Aedit.Value = app.Aedit.Value + 1;
            app.TotalPush.Value = app.TotalPush.Value + 1;
        end

        % Button pushed function: Bbutton
        function BbuttonButtonPushed(app, event)
            % Add 1 to B and the total
            app.Bedit.Value = app.Bedit.Value + 1;
            app.TotalPush.Value = app.TotalPush.Value + 1;
        end

        % Button pushed function: Cbutton
        function CbuttonButtonPushed(app, event)
            % Add 1 to C and the total
            app.Cedit.Value = app.Cedit.Value + 1;
            app.TotalPush.Value = app.TotalPush.Value + 1;
        end

        % Button pushed function: Dbutton
        function DbuttonButtonPushed(app, event)
            % Add 1 to D and the total
            app.Dedit.Value = app.Dedit.Value + 1;
            app.TotalPush.Value = app.TotalPush.Value + 1;
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)
            % Add 1 to A, B, C, and D and 4 to the total
            app.Aedit.Value = app.Aedit.Value + 1;
            app.Bedit.Value = app.Bedit.Value + 1;
            app.Cedit.Value = app.Cedit.Value + 1;
            app.Dedit.Value = app.Dedit.Value + 1;
            app.TotalPush.Value = app.TotalPush.Value + 4;
        end

        % Button pushed function: Subtract
        function SubtractButtonPushed(app, event)
            % Subtract 1 from A, B, C, and D and subtract 4 from the total
            app.Aedit.Value = app.Aedit.Value - 1;
            app.Bedit.Value = app.Bedit.Value - 1;
            app.Cedit.Value = app.Cedit.Value - 1;
            app.Dedit.Value = app.Dedit.Value - 1;
            app.TotalPush.Value = app.TotalPush.Value - 4;
        end

        % Button pushed function: Random
        function RandomButtonPushed(app, event)
            % Turn the current order into a vector
            originalOrder = [app.Aedit.Value, app.Bedit.Value, app.Cedit.Value, app.Dedit.Value];
            % Create a new vector of random numbers from 1 to 4
            randIndices = randperm(length(originalOrder));
            % Assign the new order of indices to the old one in order to
            % make a new vector of random numbers
            newOrder = originalOrder(randIndices);
            % Assign the new order of numbers to their respective edit
            % field
            app.Aedit.Value = newOrder(1);
            app.Bedit.Value = newOrder(2);
            app.Cedit.Value = newOrder(3);
            app.Dedit.Value = newOrder(4);
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            % Assign all edit fields to be 0
            app.Aedit.Value = 0;
            app.Bedit.Value = 0;
            app.Cedit.Value = 0;
            app.Dedit.Value = 0;
            app.TotalPush.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Abutton = uibutton(app.UIFigure, 'push');
            app.Abutton.ButtonPushedFcn = createCallbackFcn(app, @AbuttonPushed, true);
            app.Abutton.Text = 'A';
            app.Bbutton = uibutton(app.UIFigure, 'push');
            app.Bbutton.ButtonPushedFcn = createCallbackFcn(app, @BbuttonButtonPushed, true);
            app.Bbutton.Text = 'B';
            app.Cbutton = uibutton(app.UIFigure, 'push');
            app.Cbutton.ButtonPushedFcn = createCallbackFcn(app, @CbuttonButtonPushed, true);
            app.Cbutton.Text = 'C';
            app.Dbutton = uibutton(app.UIFigure, 'push');
            app.Dbutton.ButtonPushedFcn = createCallbackFcn(app, @DbuttonButtonPushed, true);
            app.Dbutton.Text = 'D';
            app.TotalAPushesLabel = uilabel(app.UIFigure);
            app.TotalAPushesLabel.Text = 'Total A Pushes:';
            app.Aedit = uieditfield(app.UIFigure, 'numeric');
            app.Aedit.Limits = [0 Inf];
            app.TotalBPushesLabel = uilabel(app.UIFigure);
            app.TotalBPushesLabel.Text = 'Total B Pushes:';
            app.Bedit = uieditfield(app.UIFigure, 'numeric');
            app.Bedit.Limits = [0 Inf];
            app.TotalCPushesLabel = uilabel(app.UIFigure);
            app.TotalCPushesLabel.Text = 'Total C Pushes:';
            app.Cedit = uieditfield(app.UIFigure, 'numeric');
            app.Cedit.Limits = [0 Inf];
            app.TotalDPushesLabel = uilabel(app.UIFigure);
            app.TotalDPushesLabel.Text = 'Total D Pushes:';
            app.Dedit = uieditfield(app.UIFigure, 'numeric');
            app.Dedit.Limits = [0 Inf];
            app.Add = uibutton(app.UIFigure, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Text = 'Add 1 Push to A, B, C, and D';
            app.Subtract = uibutton(app.UIFigure, 'push');
            app.Subtract.ButtonPushedFcn = createCallbackFcn(app, @SubtractButtonPushed, true);
            app.Subtract.Text = 'Subtract 1 Push to A, B, C, and D';
            app.Random = uibutton(app.UIFigure, 'push');
            app.Random.ButtonPushedFcn = createCallbackFcn(app, @RandomButtonPushed, true);
            app.Random.Text = 'Randomly Mix Up Push Counters';
            app.Reset = uibutton(app.UIFigure, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = 'Reset ALL Counters to Zero';
            app.TotalPushesABCDEditFieldLabel = uilabel(app.UIFigure);
            app.TotalPushesABCDEditFieldLabel.Text = 'Total Pushes (A, B, C, & D):';
            app.TotalPush = uieditfield(app.UIFigure, 'numeric');
            app.TotalPush.Limits = [0 Inf];
            app.TotalPush.Editable = 'off';
            app.ByAndrewCongdonLabel = uilabel(app.UIFigure);
            app.ByAndrewCongdonLabel.Text = 'By: Andrew Congdon';
            app.ButtonPushTrackerv100Label = uilabel(app.UIFigure);
            app.ButtonPushTrackerv100Label.Text = 'Button Push Tracker v1.0.0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ButtonPushTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end