classdef MemeSelectorTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ByAndrewCongdonLabel        matlab.ui.control.Label
        v100Label                   matlab.ui.control.Label
        MemeSelectorLabel           matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        SelectionButtonButtonGroup  matlab.ui.container.ButtonGroup
        Meme3Button                 matlab.ui.control.RadioButton
        Meme2Button                 matlab.ui.control.RadioButton
        Meme1Button                 matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % Have button one selected when app starts and display 
           % cooresponding values for the image and label of the image
            app.Meme1Button.Value = 1;
            app.Image.ImageSource = 'meme1.jpg';
            app.Label.Text = 'Meme 1 is currently selected!';
        end

        % Selection changed function: SelectionButtonButtonGroup
        function SelectionButtonButtonGroupSelectionChanged(app, event)
          % Use if statement to determine which button is pressed, and then
          % display cooresponding values for the image and label of the
          % image
            if  app.Meme1Button.Value == 1
                app.Image.ImageSource = 'meme1.jpg';
                app.Label.Text = 'Meme 1 is currently selected!';
            elseif app.Meme2Button.Value == 1
                app.Image.ImageSource = 'meme2.jpg';
                app.Label.Text = 'Meme 2 is currently selected!';
            elseif app.Meme3Button.Value == 1
                app.Image.ImageSource = 'meme3.jpg';
                app.Label.Text = 'Meme 3 is currently selected!';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectionButtonButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectionButtonButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectionButtonButtonGroupSelectionChanged, true);
            app.SelectionButtonButtonGroup.Title = 'Selection Button';
            app.Meme1Button = uiradiobutton(app.SelectionButtonButtonGroup);
            app.Meme1Button.Text = 'Meme 1';
            app.Meme1Button.Value = true;
            app.Meme2Button = uiradiobutton(app.SelectionButtonButtonGroup);
            app.Meme2Button.Text = 'Meme 2';
            app.Meme3Button = uiradiobutton(app.SelectionButtonButtonGroup);
            app.Meme3Button.Text = 'Meme 3';
            app.Image = uiimage(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.MemeSelectorLabel = uilabel(app.UIFigure);
            app.MemeSelectorLabel.Text = 'MemeSelector';
            app.v100Label = uilabel(app.UIFigure);
            app.v100Label.Text = 'v1.0.0';
            app.ByAndrewCongdonLabel = uilabel(app.UIFigure);
            app.ByAndrewCongdonLabel.Text = 'By: Andrew Congdon';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MemeSelectorTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end